classdef StewartSimApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        SineToggleButton     matlab.ui.control.Button
        StatusLabel          matlab.ui.control.Label
        HeaveEditField       matlab.ui.control.NumericEditField
        HeaveEditFieldLabel  matlab.ui.control.Label
        PitchEditField       matlab.ui.control.NumericEditField
        PitchEditFieldLabel  matlab.ui.control.Label
        RollEditField        matlab.ui.control.NumericEditField
        RollEditFieldLabel   matlab.ui.control.Label
        RunSimButton         matlab.ui.control.Button
        RightPanel           matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Property % Description
        UseSineWave = false; % 是否使用正弦信号作为 roll 输入
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimButton
        function RunSimButtonPushed(app, event)
                try
        % 获取用户输入
        roll_deg = app.RollEditField.Value;
        pitch_deg = app.PitchEditField.Value;
        heave = app.HeaveEditField.Value;

        % 将变量发送到 base 工作区
        assignin('base', 'roll_deg', roll_deg);
        assignin('base', 'pitch_deg', pitch_deg);
        assignin('base', 'heave', heave);

        % 运行模型（确保模型路径已添加或打开）
        simOut = sim('wave_simulation', 'StopTime', '10');

        % 显示成功信息
        app.StatusLabel.Text = '✅ 仿真成功完成';

    catch ME
        % 显示错误信息
        app.StatusLabel.Text = ['❌ 仿真失败: ', ME.message];
    end
        end

        % Button pushed function: SineToggleButton
        function SineToggleButtonPushed(app, event)

    app.UseSineWave = ~app.UseSineWave;  % 状态翻转

    if app.UseSineWave
        % 正弦信号：15*sin(2*pi*t/T), T = 4*pi
        t = linspace(0, 10, 1000);  % 仿真 5 秒，共 500 点
        T = 4 * pi;
        roll_vals = 15 * sin(2*pi*t / T);
        roll_ts = timeseries(roll_vals, t);

        assignin('base', 'roll_deg', roll_ts);  % timeseries 给 Simulink

        app.SineToggleButton.Text = '关闭正弦 Roll';
        app.StatusLabel.Text = '📈 正弦 Roll 输入已启用';
        
        try
            % 运行模型（确保模型路径已添加或打开）
            simOut = sim('wave_simulation', 'StopTime', '10');
            
            % 显示成功信息
            app.StatusLabel.Text = '✅ 仿真成功完成';
        catch ME
            % 显示错误信息
            app.StatusLabel.Text = ['❌ 仿真失败: ', ME.message];
        end
        
    else
        % 还原为用户输入的定值
        roll_deg = app.RollEditField.Value;
        assignin('base', 'roll_deg', roll_deg);

        app.SineToggleButton.Text = '启用正弦 Roll';
        app.StatusLabel.Text = '🔁 恢复固定 Roll';
    end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RunSimButton = uibutton(app.LeftPanel, 'push');
            app.RunSimButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimButtonPushed, true);
            app.RunSimButton.Text = 'RunSimButton';
            app.RollEditFieldLabel = uilabel(app.LeftPanel);
            app.RollEditFieldLabel.Text = 'RollEditField';
            app.RollEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PitchEditFieldLabel = uilabel(app.LeftPanel);
            app.PitchEditFieldLabel.Text = 'PitchEditField';
            app.PitchEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HeaveEditFieldLabel = uilabel(app.LeftPanel);
            app.HeaveEditFieldLabel.Text = 'HeaveEditField';
            app.HeaveEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StatusLabel = uilabel(app.LeftPanel);
            app.StatusLabel.Text = 'StatusLabel';
            app.SineToggleButton = uibutton(app.LeftPanel, 'push');
            app.SineToggleButton.ButtonPushedFcn = createCallbackFcn(app, @SineToggleButtonPushed, true);
            app.SineToggleButton.Text = 'SineToggleButton';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StewartSimApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end